classdef Calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UnoButton             matlab.ui.control.Button
        DosButton             matlab.ui.control.Button
        TresButton            matlab.ui.control.Button
        SeisButton            matlab.ui.control.Button
        CincoButton           matlab.ui.control.Button
        CuatroButton          matlab.ui.control.Button
        NueveButton           matlab.ui.control.Button
        OchoButton            matlab.ui.control.Button
        SieteButton           matlab.ui.control.Button
        CeroButton            matlab.ui.control.Button
        RestaButton           matlab.ui.control.Button
        SumaButton            matlab.ui.control.Button
        IgualButton           matlab.ui.control.Button
        DivisionButton        matlab.ui.control.Button
        MultiplicacionButton  matlab.ui.control.Button
        InfoButton            matlab.ui.control.Button
        ACButton              matlab.ui.control.Button
        ResultadoEditField    matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CeroButton, CincoButton, 
        % CuatroButton, DivisionButton, DosButton, 
        % MultiplicacionButton, NueveButton, OchoButton, 
        % RestaButton, SeisButton, SieteButton, SumaButton, 
        % TresButton, UnoButton
        function btnPreisonado(app, event)

            app.ResultadoEditField.Value = [app.ResultadoEditField.Value event.Source.Text];
        end

        % Button pushed function: IgualButton
        function IgualButtonPushed(app, event)
            app.ResultadoEditField.Value = num2str(eval(app.ResultadoEditField.Value));
        end

        % Button pushed function: ACButton
        function ACButtonPushed(app, event)
            app.ResultadoEditField.Value = '';
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            msgbox('Calculadora sencilla', 'Acerca de...')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UnoButton = uibutton(app.UIFigure, 'push');
            app.UnoButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.UnoButton.Text = '1';
            app.DosButton = uibutton(app.UIFigure, 'push');
            app.DosButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.DosButton.Text = '2';
            app.TresButton = uibutton(app.UIFigure, 'push');
            app.TresButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.TresButton.Text = '3';
            app.SeisButton = uibutton(app.UIFigure, 'push');
            app.SeisButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.SeisButton.Text = '6';
            app.CincoButton = uibutton(app.UIFigure, 'push');
            app.CincoButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.CincoButton.Text = '5';
            app.CuatroButton = uibutton(app.UIFigure, 'push');
            app.CuatroButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.CuatroButton.Text = '4';
            app.NueveButton = uibutton(app.UIFigure, 'push');
            app.NueveButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.NueveButton.Text = '9';
            app.OchoButton = uibutton(app.UIFigure, 'push');
            app.OchoButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.OchoButton.Text = '8';
            app.SieteButton = uibutton(app.UIFigure, 'push');
            app.SieteButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.SieteButton.Text = '7';
            app.CeroButton = uibutton(app.UIFigure, 'push');
            app.CeroButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.CeroButton.Text = '0';
            app.RestaButton = uibutton(app.UIFigure, 'push');
            app.RestaButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.RestaButton.Text = '-';
            app.SumaButton = uibutton(app.UIFigure, 'push');
            app.SumaButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.SumaButton.Text = '+';
            app.IgualButton = uibutton(app.UIFigure, 'push');
            app.IgualButton.ButtonPushedFcn = createCallbackFcn(app, @IgualButtonPushed, true);
            app.IgualButton.Text = '=';
            app.DivisionButton = uibutton(app.UIFigure, 'push');
            app.DivisionButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.DivisionButton.Text = '/';
            app.MultiplicacionButton = uibutton(app.UIFigure, 'push');
            app.MultiplicacionButton.ButtonPushedFcn = createCallbackFcn(app, @btnPreisonado, true);
            app.MultiplicacionButton.Text = '*';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.ACButton = uibutton(app.UIFigure, 'push');
            app.ACButton.ButtonPushedFcn = createCallbackFcn(app, @ACButtonPushed, true);
            app.ACButton.Text = 'AC';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end