classdef appCalcDerivadasIntegrales_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FuncinEditFieldLabel    matlab.ui.control.Label
        FuncionEditField        matlab.ui.control.EditField
        DerivadaEditFieldLabel  matlab.ui.control.Label
        DerivadaEditField       matlab.ui.control.EditField
        IntegralEditFieldLabel  matlab.ui.control.Label
        IntegralEditField       matlab.ui.control.EditField
        CalcularButton          matlab.ui.control.Button
        LimpiarButton           matlab.ui.control.Button
        CerrarButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            syms x;
            ecuacion = eval(app.FuncionEditField.Value);
            
            % Cal. de derivada
            derivada = diff(ecuacion);
            strDerivada = char(derivada);
            app.DerivadaEditField.Value = strDerivada;
            
            % Cal. de integral
            integral = int(ecuacion);
            strIntegral = char(integral);
            app.IntegralEditField.Value = strIntegral;
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.FuncionEditField.Value = '';
            app.DerivadaEditField.Value = '';
            app.IntegralEditField.Value = '';
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FuncinEditFieldLabel = uilabel(app.UIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncionEditField = uieditfield(app.UIFigure, 'text');
            app.DerivadaEditFieldLabel = uilabel(app.UIFigure);
            app.DerivadaEditFieldLabel.Text = 'Derivada';
            app.DerivadaEditField = uieditfield(app.UIFigure, 'text');
            app.IntegralEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralEditFieldLabel.Text = 'Integral';
            app.IntegralEditField = uieditfield(app.UIFigure, 'text');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCalcDerivadasIntegrales_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end