classdef appGrapV2_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        InitialtimeEditFieldLabel  matlab.ui.control.Label
        InitialtimeEditField       matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel      matlab.ui.control.Label
        EndtimeEditField           matlab.ui.control.NumericEditField
        PlotButton                 matlab.ui.control.Button
        AutoupdateSwitchLabel      matlab.ui.control.Label
        AutoupdateSwitch           matlab.ui.control.Switch
        Lamp                       matlab.ui.control.Lamp
        FrecuencyKnobLabel         matlab.ui.control.Label
        FrecuencyKnob              matlab.ui.control.Knob
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        GridSwitchLabel            matlab.ui.control.Label
        GridSwitch                 matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
       autoUpdate = true
    end
    
    properties (Constant)
        COLOR_VERDE = [0 1 0];
        COLOR_GRIS = [0.8 0.8 0.8];
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            initialTime = app.InitialtimeEditField.Value;
            endTime = app.EndtimeEditField.Value;
            frec = app.FrecuencyKnob.Value;
            
            t = linspace(initialTime, endTime, 200);
            y = sin(2 * pi * frec * t);
            plot(app.UIAxes, t, y);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updatePlot()
        end

        % Value changed function: GridSwitch
        function GridSwitchValueChanged(app, event)
            value = app.GridSwitch.Value;
            grid(app.UIAxes, value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {354, 354};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {451, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: AutoupdateSwitch
        function AutoupdateSwitchValueChanged(app, event)
            value = app.AutoupdateSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp.Color = app.COLOR_VERDE;
                app.PlotButton.Enable = false;
                app.autoUpdate = true;
            else
                app.Lamp.Color = app.COLOR_GRIS;
                app.PlotButton.Enable = true;
                app.autoUpdate = false;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.updatePlot()
        end

        % Value changed function: EndtimeEditField, FrecuencyKnob, 
        % InitialtimeEditField
        function FrecuencyKnobValueChanged(app, event)
            if app.autoUpdate
                app.updatePlot()    
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InitialtimeEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialtimeEditFieldLabel.Text = 'Initial time';
            app.InitialtimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialtimeEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuencyKnobValueChanged, true);
            app.InitialtimeEditField.Value = 1;
            app.EndtimeEditFieldLabel = uilabel(app.LeftPanel);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuencyKnobValueChanged, true);
            app.EndtimeEditField.Value = 2;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.AutoupdateSwitchLabel = uilabel(app.LeftPanel);
            app.AutoupdateSwitchLabel.Text = 'Auto update';
            app.AutoupdateSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AutoupdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoupdateSwitchValueChanged, true);
            app.AutoupdateSwitch.Value = 'On';
            app.Lamp = uilamp(app.LeftPanel);
            app.FrecuencyKnobLabel = uilabel(app.LeftPanel);
            app.FrecuencyKnobLabel.Text = 'Frecuency';
            app.FrecuencyKnob = uiknob(app.LeftPanel, 'continuous');
            app.FrecuencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrecuencyKnobValueChanged, true);
            app.FrecuencyKnob.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            app.GridSwitchLabel = uilabel(app.RightPanel);
            app.GridSwitchLabel.Text = 'Grid';
            app.GridSwitch = uiswitch(app.RightPanel, 'slider');
            app.GridSwitch.ValueChangedFcn = createCallbackFcn(app, @GridSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGrapV2_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end