classdef appGraphV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TypeGraphButtonGroup  matlab.ui.container.ButtonGroup
        LineButton            matlab.ui.control.RadioButton
        ScatterButton         matlab.ui.control.RadioButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x 
        y
    end
    
    methods (Access = private)
        
        function updatePlot(app, typeGraph)
            if strcmp(typeGraph, 'Line')
                plot(app.UIAxes, app.x, app.y);
            else
                scatter(app.UIAxes, app.x, app.y);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x = linspace(0, 10, 50);
            app.y = app.x .^ 2;
            
            app.updatePlot('Line')
        end

        % Selection changed function: TypeGraphButtonGroup
        function TypeGraphButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeGraphButtonGroup.SelectedObject;
            app.updatePlot(selectedButton.Text)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TypeGraphButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeGraphButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeGraphButtonGroupSelectionChanged, true);
            app.TypeGraphButtonGroup.Title = 'Type Graph';
            app.LineButton = uiradiobutton(app.TypeGraphButtonGroup);
            app.LineButton.Text = 'Line';
            app.LineButton.Value = true;
            app.ScatterButton = uiradiobutton(app.TypeGraphButtonGroup);
            app.ScatterButton.Text = 'Scatter';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Edades')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGraphV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end