classdef appMensajes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AvisoButton     matlab.ui.control.Button
        ErrorButton     matlab.ui.control.Button
        AyudaButton     matlab.ui.control.Button
        InfoButton      matlab.ui.control.Button
        PreguntaButton  matlab.ui.control.Button
        EntradaButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AvisoButton
        function AvisoButtonPushed(app, event)
            %warndlg('Esto es un aviso', 'Curso GUI')
            uialert(app.UIFigure,'Esto es un aviso','Curso GUI');
        end

        % Button pushed function: ErrorButton
        function ErrorButtonPushed(app, event)
            errordlg('Esto es un mensaje de error', 'Curso GUI')
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            helpdlg('Esto es una ayuda', 'Curso GUI')
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            msgbox('Esto es un cuadro de mensaje', 'Curso GUI')
        end

        % Button pushed function: PreguntaButton
        function PreguntaButtonPushed(app, event)
            opc = questdlg('Desea salir del programa', 'SALIR', ...
                'Si', 'No');
            if strcmp(opc, 'No')
                return
            else
                delete(app)
            end
        end

        % Button pushed function: EntradaButton
        function EntradaButtonPushed(app, event)
            dato = inputdlg('DATO', 'Encabezado');
            disp(dato{1})
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AvisoButton = uibutton(app.UIFigure, 'push');
            app.AvisoButton.ButtonPushedFcn = createCallbackFcn(app, @AvisoButtonPushed, true);
            app.AvisoButton.Text = 'Aviso';
            app.ErrorButton = uibutton(app.UIFigure, 'push');
            app.ErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ErrorButtonPushed, true);
            app.ErrorButton.Text = 'Error';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.PreguntaButton = uibutton(app.UIFigure, 'push');
            app.PreguntaButton.ButtonPushedFcn = createCallbackFcn(app, @PreguntaButtonPushed, true);
            app.PreguntaButton.Text = 'Pregunta';
            app.EntradaButton = uibutton(app.UIFigure, 'push');
            app.EntradaButton.ButtonPushedFcn = createCallbackFcn(app, @EntradaButtonPushed, true);
            app.EntradaButton.Text = 'Entrada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMensajes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end