classdef EjemploListBoxBandas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BandaLabel         matlab.ui.control.Label
        BandaNameListBox   matlab.ui.control.ListBox
        InfoBandaTextArea  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InfoBandaTextArea.Value = ['UK' ' 1973'];
            app.BandaLabel.Text = app.BandaNameListBox.Items{1};
        end

        % Value changed function: BandaNameListBox
        function BandaNameListBoxValueChanged(app, event)
            numband = str2num(app.BandaNameListBox.Value);
            
            switch numband
                case 1
                    info = ['UK' ' 1973'];
                case 2
                    info = ['ESP' ' 1973'];
            end
            
            app.InfoBandaTextArea.Value = info;
            app.BandaLabel.Text = app.BandaNameListBox.Items{numband};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BandaLabel = uilabel(app.UIFigure);
            app.BandaLabel.Text = {'Banda'; ''};
            app.BandaNameListBox = uilistbox(app.UIFigure);
            app.BandaNameListBox.Items = {'Judas Priest', 'Iron Maiden'};
            app.BandaNameListBox.ItemsData = {'1', '2'};
            app.BandaNameListBox.ValueChangedFcn = createCallbackFcn(app, @BandaNameListBoxValueChanged, true);
            app.BandaNameListBox.Value = '1';
            app.InfoBandaTextArea = uitextarea(app.UIFigure);
            app.InfoBandaTextArea.Tooltip = {'Área de texto'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EjemploListBoxBandas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end