classdef EjemploSlide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RojoSliderLabel   matlab.ui.control.Label
        RojoSlider        matlab.ui.control.Slider
        VerdeSliderLabel  matlab.ui.control.Label
        VerdeSlider       matlab.ui.control.Slider
        AzulSliderLabel   matlab.ui.control.Label
        AzulSlider        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        R % Color Rojo
        G % Color Verde
        B % Color Azul
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.R = 0.94;
            app.G = 0.94;
            app.B = 0.94;
            app.UIFigure.Color = [app.R app.G app.B];
        end

        % Value changing function: RojoSlider
        function RojoSliderValueChanging(app, event)
            app.R = event.Value;
            
            app.UIFigure.Color = [app.R app.G app.B];
        end

        % Value changing function: VerdeSlider
        function VerdeSliderValueChanging(app, event)
            app.G = event.Value;
            
            app.UIFigure.Color = [app.R app.G app.B];
            
        end

        % Value changing function: AzulSlider
        function AzulSliderValueChanging(app, event)
            app.B = event.Value;
            
            app.UIFigure.Color = [app.R app.G app.B];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RojoSliderLabel = uilabel(app.UIFigure);
            app.RojoSliderLabel.Text = 'Rojo';
            app.RojoSlider = uislider(app.UIFigure);
            app.RojoSlider.Limits = [0 1];
            app.RojoSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 1];
            app.RojoSlider.ValueChangingFcn = createCallbackFcn(app, @RojoSliderValueChanging, true);
            app.VerdeSliderLabel = uilabel(app.UIFigure);
            app.VerdeSliderLabel.Text = {'Verde'; ''};
            app.VerdeSlider = uislider(app.UIFigure);
            app.VerdeSlider.Limits = [0 1];
            app.VerdeSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 1];
            app.VerdeSlider.ValueChangingFcn = createCallbackFcn(app, @VerdeSliderValueChanging, true);
            app.AzulSliderLabel = uilabel(app.UIFigure);
            app.AzulSliderLabel.Text = 'Azul';
            app.AzulSlider = uislider(app.UIFigure);
            app.AzulSlider.Limits = [0 1];
            app.AzulSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 1];
            app.AzulSlider.ValueChangingFcn = createCallbackFcn(app, @AzulSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EjemploSlide

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end